classdef path_create_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SetY2EditField       matlab.ui.control.NumericEditField
        DLabel_2             matlab.ui.control.Label
        SetX2EditField       matlab.ui.control.NumericEditField
        XLabel_2             matlab.ui.control.Label
        ExportStatusLabel    matlab.ui.control.Label
        SetPositionButton    matlab.ui.control.Button
        SetPositionLabel     matlab.ui.control.Label
        SetY1EditField       matlab.ui.control.NumericEditField
        DLabel               matlab.ui.control.Label
        SetX1EditField       matlab.ui.control.NumericEditField
        XLabel               matlab.ui.control.Label
        P2YLabel             matlab.ui.control.Label
        P2XLabel             matlab.ui.control.Label
        P2Label              matlab.ui.control.Label
        P1YLabel             matlab.ui.control.Label
        P1XLabel             matlab.ui.control.Label
        P1Label              matlab.ui.control.Label
        CreatePanel          matlab.ui.container.Panel
        TabGroup2            matlab.ui.container.TabGroup
        PointTab_2           matlab.ui.container.Tab
        PointYEditField      matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        PointXEditField      matlab.ui.control.NumericEditField
        XEditFieldLabel      matlab.ui.control.Label
        CreatePoint          matlab.ui.control.Button
        LineSegTab_2         matlab.ui.container.Tab
        LSX2EditField        matlab.ui.control.NumericEditField
        X2EditFieldLabel     matlab.ui.control.Label
        LSY2EditField        matlab.ui.control.NumericEditField
        Y2EditFieldLabel     matlab.ui.control.Label
        LSY1EditField        matlab.ui.control.NumericEditField
        Y1EditFieldLabel     matlab.ui.control.Label
        LSX1EditField        matlab.ui.control.NumericEditField
        X1EditFieldLabel     matlab.ui.control.Label
        CreateLS             matlab.ui.control.Button
        ValidateStatusLabel  matlab.ui.control.Label
        ExportButton         matlab.ui.control.Button
        ValidateButton       matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Frame 
        Selected % for the graphical part
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function update_point(~, ax, plotp)
%             point.Pos = ax.CurrentPoint(1, 1:2);
            plotp.UserData.Pos = ax.CurrentPoint(1, 1:2);
        end
        
        function redraw_poly(~, axh, varargin)
            % pasted from draggableDemo
            % works for one linesegment, but not two

            % Deleting the previous polygon
            delete(getappdata(axh, "ls"));
            
            % Retrieving the vertex vector and corresponding xdata and ydata
            points = getappdata(axh, "points");
            xdata = cell2mat(get(points, "xdata"));
            ydata = cell2mat(get(points, "ydata"));
            
            % Plotting the new polygon and saving its handle as application data
            ls = plot(axh, [xdata.' xdata(1)],[ydata.' ydata(1)], "Color", "k");
            setappdata(axh, "ls", ls);
            
            % Putting the vertices on top of the polygon so that they are easier to drag (or
            % else, the polygone line get in the way)
             set(axh, "Children", [points ls]);
            
        end
        
        function redraw_ls_frompoint(app, point, lineplot)
            % if linesegment is dragged by the point
            % update endp position, redraw line
            % is the linesegment a draggable or a line?

            %% new version
            ax = app.UIAxes;
            newpoint = ax.CurrentPoint(1,1:2);
            endp = point.UserData;
            endp.setPos(newpoint(1), newpoint(2));

            oldX = lineplot.XData;
            oldY = lineplot.YData;

            if isequal(point.UserData, lineplot.UserData.P1)
                % p1 was dragged

                lineplot.XData = [endp.Pos(1) oldX(2)];
                lineplot.YData = [endp.Pos(2) oldY(2)];
            else 
                % p2 was dragged

                lineplot.XData = [oldX(1) endp.Pos(1)];
                lineplot.YData = [oldY(1) endp.Pos(2)];

                % display backend
                app.P2XLabel.Text = num2str(endp.Pos(1));
                app.P2YLabel.Text = num2str(endp.Pos(2));
            end

            %% original version

% %             endp.Pos = ax.CurrentPoint(1,1:2);
%             newpoint = ax.CurrentPoint(1,1:2);
%             endp.setPos(newpoint(1), newpoint(2));
% 
%             oldX = lineplot.XData;
%             oldY = lineplot.YData;
%             if num == 1 
%                 % p1 was dragged
% 
%                 % if lineplot is a line
%                 lineplot.XData = [endp.Pos(1) oldX(2)];
%                 lineplot.YData = [endp.Pos(2) oldY(2)];
% 
%                 % if lineplot is a draggable
% %                 lineplot.set_position([endp.Pos(1) oldX(2) ; ...
% %                                        endp.Pos(2) oldY(2)])
% 
%             % display backend
%             app.P1XLabel.Text = num2str(endp.Pos(1));
%             app.P1YLabel.Text = num2str(endp.Pos(2));
% 
%             elseif num == 2
%                 % p2 was dragged
% 
%                 % if lineplot is a line
%                 lineplot.XData = [oldX(1) endp.Pos(1)];
%                 lineplot.YData = [oldY(1) endp.Pos(2)];
% 
%                 % if lineplot is a draggable
% %                 lineplot.set_position([oldX(1) endp.Pos(1) ; ...
% %                                        oldY(1) endp.Pos(2)])
%                 % display backend
%                 app.P2XLabel.Text = num2str(endp.Pos(1));
%                 app.P2YLabel.Text = num2str(endp.Pos(2));
% 
%             else
%                 error("Specify which endpoint was dragged with 1 or 2")
%             end
% 
% %             set(ax, "Children", [pointplot lineplot]);
% 
%             % display plot objects
% %             app.P1XLabel.Text = num2str(lineplot.XData(1));
% %             app.P1YLabel.Text = num2str(lineplot.YData(1));
% %             app.P2XLabel.Text = num2str(lineplot.XData(2));
% %             app.P2YLabel.Text = num2str(lineplot.YData(2));
            
            
        end
        
        function redraw_ls_fromline(~, lineplot, p1, p2)
            % if linesegment is dragged by the line
            % update both endp positions
            % draggable objects can set_position
            
            newX = lineplot.XData;
            newY = lineplot.YData;    

            p1.XData = newX(1);
            p1.YData = newY(1);
            p2.XData = newX(2);
            p2.YData = newY(2);    

            line = lineplot.UserData;
            line.P1.setPos(newX(1), newY(1));
            line.P2.setPos(newX(2), newY(2));        

%             newX = lineplot.XData;
%             newY = lineplot.YData;
% %             line.P1.Pos = [newX(1) newY(1)];
% %             line.P2.Pos = [newX(2) newY(2)];
%             line.P1.setPos(newX(1), newY(1));
%             line.P2.setPos(newX(2), newY(2));
% 
%             p1.XData = newX(1);
%             p1.YData = newY(1);
%             p2.XData = newX(2);
%             p2.YData = newY(2);
%             
%             % display plot objects
% %             app.P1XLabel.Text = num2str(lineplot.XData(1));
% %             app.P1YLabel.Text = num2str(lineplot.YData(1));
% %             app.P2XLabel.Text = num2str(lineplot.XData(2));
% %             app.P2YLabel.Text = num2str(lineplot.YData(2));
% 
%             % display backend
%             app.P1XLabel.Text = num2str(line.P1.Pos(1));
%             app.P1YLabel.Text = num2str(line.P1.Pos(2));
%             app.P2XLabel.Text = num2str(line.P2.Pos(1));
%             app.P2YLabel.Text = num2str(line.P2.Pos(2));
        end
        
        function lineCallback(app, src, line)
            src.Color = 'r'
            % frame's selected property is instantiated to be a 
            % selected object, which contains a segments list
            % segments list is currently just a singular line
            app.Frame.Selected = Group([line])
            app.Selected = src % the graphics object
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Frame = Frame;
            
            ax = app.UIAxes();
            axis(ax, "equal");
            set(ax, "DataAspectRatio", [1 1 1], "Xlim", [-2 2], "YLim", [-2 2]);
            hold(ax, "on")
        end

        % Button pushed function: CreatePoint
        function CreatePointPushed(app, event)
%             x = app.PointXEditField;
%             y = app.PointYEditField;
%             p = Point(app.Frame, x, y);
% 
%             plotp = plot(app.UIAxes, x, y);
%             draggable(plotp, @(g)update_point(app, app.UIAxes, p));
% 
%             % for visible vertices
%             set(plotp, "Marker", "o", "MarkerSize", 10, "MarkerFaceColor", "b", ...
%             "MarkerEdgeColor", "none");
            
            x = app.PointXEditField.Value;
            y = app.PointYEditField.Value;
            p = Point(app.Frame, x, y);

            plotp = plot(app.UIAxes, x, y);
            plotp.UserData = p;
            draggable(plotp, @(g)update_point(app, app.UIAxes, plotp));

            % for visible vertices
            set(plotp, "Marker", "o", "MarkerSize", 10, "Color", "b", ...
                "MarkerFaceColor", "b", 'LineWidth', 2);
        end

        % Button pushed function: CreateLS
        function CreateLSButtonPushed(app, event)
            
            %% new version
            ax = app.UIAxes;
            x1 = app.LSX1EditField.Value;
            y1 = app.LSY1EditField.Value;
            x2 = app.LSX2EditField.Value;
            y2 = app.LSY2EditField.Value;
            lineseg = LineSegment(app.Frame, x1, y1, x2, y2);

            hg = hgtransform('Parent',ax);

            % make line
            lineplot = line(ax, [x1 x2], [y1 y2], 'Parent', hg);
            % store linesegment backend in line plot
            lineplot.UserData = lineseg;
            set(lineplot, "Color", "b", "LineWidth", 2);
            
            % make vertices
            p1 = plot(ax, x1, y1, 'Parent', hg);
            p2 = plot(ax, x2, y2, 'Parent', hg);
            % store backend in each line plot
            p1.UserData = lineseg.P1;
            p2.UserData = lineseg.P2;
            % make vertices visible
            points = [p1 p2];
            set(points, "Marker", "o", "MarkerSize", 10, "Color", "b", ...
                "MarkerFaceColor", "b", 'LineWidth', 2);

%             % group 2 points and line under one hgtransform group
%             set(p1, 'Parent', hg);
%             set(p2, 'Parent', hg);
%             set(lineplot, 'Parent', hg);
%             hg.HandleVisibility = 'off';

            draggable(p1, @(g)redraw_ls_frompoint(app, p1, lineplot));
            draggable(p2, @(g)redraw_ls_frompoint(app, p2, lineplot));
            draggable(lineplot, @(g)redraw_ls_fromline(app, lineplot, p1, p2));
            
            %% original version

%             ax = app.UIAxes;
%             
%             x1 = app.LSX1EditField.Value;
%             y1 = app.LSY1EditField.Value;
%             x2 = app.LSX2EditField.Value;
%             y2 = app.LSY2EditField.Value;
%             l = LineSegment(app.Frame, x1, y1, x2, y2);
% 
%             p1 = plot(ax, x1, y1);
%             p2 = plot(ax, x2, y2);
%             points = [p1 p2];
% 
%             % for visible vertices
%             set(points, "Marker", "o", "MarkerSize", 10, "MarkerFaceColor", "b", ...
%             "MarkerEdgeColor", "none");
%             
%             % draggable line segment
% %             lineplot = line(ax, [x1 x2], [y1 y2]);
%             lineplot = line(ax, [x1 x2], [y1 y2], 'ButtonDownFcn', {@lineCallback, l});
%             set(lineplot, "Color", "m", "LineWidth", 2);
%             
% 
% %             setappdata(ax, "points", points);
% %             setappdata(ax, "ls", []);
% 
% 
%             draggable(p1, @(g)redraw_ls_frompoint(app, ax, p1, l.P1, lineplot, 1));
%             draggable(p2, @(g)redraw_ls_frompoint(app, ax, p2, l.P2, lineplot, 2));
%             draggable(lineplot, @(g)redraw_ls_fromline(app, ax, p1, p2, l, lineplot));
% 
% %             redraw_ls(app, ax);

        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            try 
                for i = 1 : length(app.Frame.SegmentList)
                    app.Frame.SegmentList(i).P1.Pos
                    isempty(app.Frame.SegmentList(i).P1.Connected)
                    app.Frame.SegmentList(i).P2.Pos
                    isempty(app.Frame.SegmentList(i).P2.Connected)
                end
                app.Frame.validate;
                app.ValidateStatusLabel.Text = 'Success!';
            catch 
                warning("Path validation was unsuccessful")
                app.ValidateStatusLabel.Text = 'Path validation was unsuccessful';
            end
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            try
                app.Frame.export;
                app.ExportStatusLabel.Text = 'Success!';
            catch
                warning("Export failed. Please first complete validated path.");
                app.ExportStatusLabel.Text = 'Export was unsuccessful';
            end
        end

        % Button pushed function: SetPositionButton
        function SetPositionButtonPushed(app, event)
            x1 = app.SetX1EditField.Value;
            y1 = app.SetY1EditField.Value;
            x2 = app.SetX2EditField.Value;
            y2 = app.SetY2EditField.Value;

%             select = app.Frame.Selected;
%             select.setPos(x1,y1,x2,y2);
%             app.Selected.XData = [x1 x2];
%             app.Selected.YData = [y1 y2];

            selected = app.UIAxes.UserData;
            if isa(selected.UserData, 'Point')
                selected.XData = x1;
                selected.YData = y1;
                % backend point
                selected.UserData.Pos = [x1,y1];
                disp(selected.UserData.Pos)
            else
                disp("this is a lineseg")
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ValidateButton = uibutton(app.UIFigure, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ValidateStatusLabel = uilabel(app.UIFigure);
            app.ValidateStatusLabel.Text = 'Status';
            app.CreatePanel = uipanel(app.UIFigure);
            app.CreatePanel.Title = 'Create';
            app.TabGroup2 = uitabgroup(app.CreatePanel);
            app.PointTab_2 = uitab(app.TabGroup2);
            app.PointTab_2.Title = 'Point';
            app.CreatePoint = uibutton(app.PointTab_2, 'push');
            app.CreatePoint.ButtonPushedFcn = createCallbackFcn(app, @CreatePointPushed, true);
            app.CreatePoint.Text = 'Create';
            app.XEditFieldLabel = uilabel(app.PointTab_2);
            app.XEditFieldLabel.Text = 'X';
            app.PointXEditField = uieditfield(app.PointTab_2, 'numeric');
            app.YEditFieldLabel = uilabel(app.PointTab_2);
            app.YEditFieldLabel.Text = 'Y';
            app.PointYEditField = uieditfield(app.PointTab_2, 'numeric');
            app.LineSegTab_2 = uitab(app.TabGroup2);
            app.LineSegTab_2.Title = 'LineSeg';
            app.CreateLS = uibutton(app.LineSegTab_2, 'push');
            app.CreateLS.ButtonPushedFcn = createCallbackFcn(app, @CreateLSButtonPushed, true);
            app.CreateLS.Text = 'Create';
            app.X1EditFieldLabel = uilabel(app.LineSegTab_2);
            app.X1EditFieldLabel.Text = 'X1';
            app.LSX1EditField = uieditfield(app.LineSegTab_2, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.LineSegTab_2);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.LSY1EditField = uieditfield(app.LineSegTab_2, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.LineSegTab_2);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.LSY2EditField = uieditfield(app.LineSegTab_2, 'numeric');
            app.X2EditFieldLabel = uilabel(app.LineSegTab_2);
            app.X2EditFieldLabel.Text = 'X2';
            app.LSX2EditField = uieditfield(app.LineSegTab_2, 'numeric');
            app.LSX2EditField.Value = 1;
            app.P1Label = uilabel(app.UIFigure);
            app.P1Label.Text = 'P1';
            app.P1XLabel = uilabel(app.UIFigure);
            app.P1YLabel = uilabel(app.UIFigure);
            app.P1YLabel.Text = 'Label2';
            app.P2Label = uilabel(app.UIFigure);
            app.P2Label.Text = 'P2';
            app.P2XLabel = uilabel(app.UIFigure);
            app.P2YLabel = uilabel(app.UIFigure);
            app.P2YLabel.Text = 'Label2';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X1';
            app.SetX1EditField = uieditfield(app.UIFigure, 'numeric');
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Text = 'Y1';
            app.SetY1EditField = uieditfield(app.UIFigure, 'numeric');
            app.SetPositionLabel = uilabel(app.UIFigure);
            app.SetPositionLabel.Text = 'Set Position';
            app.SetPositionButton = uibutton(app.UIFigure, 'push');
            app.SetPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SetPositionButtonPushed, true);
            app.SetPositionButton.Text = 'Set Position';
            app.ExportStatusLabel = uilabel(app.UIFigure);
            app.ExportStatusLabel.Text = 'Status';
            app.XLabel_2 = uilabel(app.UIFigure);
            app.XLabel_2.Text = 'X2';
            app.SetX2EditField = uieditfield(app.UIFigure, 'numeric');
            app.DLabel_2 = uilabel(app.UIFigure);
            app.DLabel_2.Text = 'Y2';
            app.SetY2EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = path_create_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end