classdef path_create_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SetButton                     matlab.ui.control.Button
        SetexportresolutionEditField  matlab.ui.control.NumericEditField
        SetexportresolutionEditFieldLabel  matlab.ui.control.Label
        DisplayGroupSelectLabel       matlab.ui.control.Label
        GroupselectionLabel           matlab.ui.control.Label
        NameButton                    matlab.ui.control.Button
        FileNameEditField             matlab.ui.control.EditField
        FileNameEditFieldLabel        matlab.ui.control.Label
        DeleteButton                  matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        ExportStatusLabel             matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        ValidateStatusLabel           matlab.ui.control.Label
        ValidateButton                matlab.ui.control.Button
        TransformPanel                matlab.ui.container.Panel
        TransformTabGroup             matlab.ui.container.TabGroup
        TranslateTab                  matlab.ui.container.Tab
        TranslateButton               matlab.ui.control.Button
        TranslateYEditField           matlab.ui.control.NumericEditField
        dyLabel                       matlab.ui.control.Label
        TranslateXEditField           matlab.ui.control.NumericEditField
        dxLabel                       matlab.ui.control.Label
        ScaleTab                      matlab.ui.container.Tab
        ScaleFactorEditField          matlab.ui.control.NumericEditField
        dyLabel_3                     matlab.ui.control.Label
        AnchorLabel                   matlab.ui.control.Label
        ScaleYEditField               matlab.ui.control.NumericEditField
        dyLabel_2                     matlab.ui.control.Label
        ScaleXEditField               matlab.ui.control.NumericEditField
        dxLabel_2                     matlab.ui.control.Label
        ScaleButton                   matlab.ui.control.Button
        PivotTab                      matlab.ui.container.Tab
        PivotDegreesEditField         matlab.ui.control.NumericEditField
        dyLabel_5                     matlab.ui.control.Label
        PivotYEditField               matlab.ui.control.NumericEditField
        dyLabel_4                     matlab.ui.control.Label
        PivotXEditField               matlab.ui.control.NumericEditField
        dxLabel_3                     matlab.ui.control.Label
        AnchorLabel_2                 matlab.ui.control.Label
        PivotButton                   matlab.ui.control.Button
        LengthenTab                   matlab.ui.container.Tab
        ButtonGroup                   matlab.ui.container.ButtonGroup
        EndpointtoextendLabel         matlab.ui.control.Label
        P2Button                      matlab.ui.control.RadioButton
        P1Button                      matlab.ui.control.RadioButton
        LineSegmentonlyLabel          matlab.ui.control.Label
        LengthNewLEditField           matlab.ui.control.NumericEditField
        dyLabel_7                     matlab.ui.control.Label
        LengthenButton                matlab.ui.control.Button
        CreatePanel                   matlab.ui.container.Panel
        CreateTabGroup                matlab.ui.container.TabGroup
        PointTab                      matlab.ui.container.Tab
        SetPoint                      matlab.ui.control.Button
        PointYEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        PointXEditField               matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        CreatePoint                   matlab.ui.control.Button
        LineSegTab                    matlab.ui.container.Tab
        SetLS                         matlab.ui.control.Button
        LSX2EditField                 matlab.ui.control.NumericEditField
        X2EditFieldLabel              matlab.ui.control.Label
        LSY2EditField                 matlab.ui.control.NumericEditField
        Y2EditFieldLabel              matlab.ui.control.Label
        LSY1EditField                 matlab.ui.control.NumericEditField
        Y1EditFieldLabel              matlab.ui.control.Label
        LSX1EditField                 matlab.ui.control.NumericEditField
        X1EditFieldLabel              matlab.ui.control.Label
        CreateLS                      matlab.ui.control.Button
        ArcTab                        matlab.ui.container.Tab
        DisplayRadiusLabel            matlab.ui.control.Label
        RadiusLabel                   matlab.ui.control.Label
        DisplayP2Label                matlab.ui.control.Label
        P2Label                       matlab.ui.control.Label
        ArcThetaEditField             matlab.ui.control.NumericEditField
        AngleLabel                    matlab.ui.control.Label
        ArcPyEditField                matlab.ui.control.NumericEditField
        Y1Label                       matlab.ui.control.Label
        ArcPxEditField                matlab.ui.control.NumericEditField
        X1Label                       matlab.ui.control.Label
        ArcCyEditField                matlab.ui.control.NumericEditField
        CyLabel                       matlab.ui.control.Label
        ArcCxEditField                matlab.ui.control.NumericEditField
        CxLabel                       matlab.ui.control.Label
        SetArc                        matlab.ui.control.Button
        CreateArc                     matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Frame 
        Selected % for the graphical part
        GroupSelect 
    end
    
    properties (Access = public)
        SnapDist
    end

    methods (Access = public)
        
        function xy_r = draw_arc(~, cx, cy, px, py, theta)
            
            % Circle Function For Angles In Radians
            circr = @(radius,rad_ang)  ...
                [radius*cos(rad_ang) + cx;  radius*sin(rad_ang) + cy];
            % Circle Function For Angles In Degrees
            circd = @(radius,deg_ang)  ...
                [radius*cosd(deg_ang) + cx;  radius*sind(deg_ang) + cy];
            
            % Number Of Points In Complete Circle
            N = 100;
            % starting angle from C to P
            pangle = atan2d(py - cy,px - cx); 
            % Angle Defining Arc Segment (degrees)
            r_angl = linspace(pangle, pangle + theta, N);
            radius = norm([px py] - [cx cy]);
            % Matrix (2xN) Of (x,y) Coordinates
            xy_r = circd(radius,r_angl);

        end

        function val = dist(~, pos1, pos2)
            % takes array arguments [a,b]
            val = norm(pos1 - pos2);
        end

        function val = dir(~, pos1, pos2)
            val = atan2d(pos2(2) - pos1(2),pos2(1) - pos1(1)); 
        end
    end
        
    
    methods (Access = private)
        
        function update_point(app, plotp)
%             plotp.UserData.Pos = app.UIAxes.CurrentPoint(1, 1:2);
            
            % round to 0.1
            newpoint = round(app.UIAxes.CurrentPoint(1, 1:2),1);

            plotp.UserData.Pos = newpoint;
            plotp.XData(1) = newpoint(1);
            plotp.YData(1) = newpoint(2);

            app.CreateTabGroup.SelectedTab = app.PointTab;
            app.PointXEditField.Value = plotp.XData(1);
            app.PointYEditField.Value = plotp.YData(1);
        end
        
        
        function redraw_ls_frompoint(app, point, lineplot, newpoint)
            % if linesegment is dragged by the point
            % update endp position, redraw line
            % is the linesegment a draggable or a line?

            endp = point.UserData;
            pointList = endp.Segment.Frame.PointList;
            segList = endp.Segment.Frame.SegmentList;

            for i = 1:length(segList)                
                if ~isequal(segList(i), endp.Segment)
                    if dist(app, segList(i).P1.Pos, newpoint) < app.SnapDist
                        newpoint = segList(i).P1.Pos;
                    elseif dist(app, segList(i).P2.Pos, newpoint) < app.SnapDist
                        newpoint = segList(i).P2.Pos;
                    end
                end
            end
            for i = 1:length(pointList)
                if dist(app, pointList(i).Pos, newpoint) < app.SnapDist
                    newpoint = pointList(i).Pos;
                end
            end

            endp.setPos(newpoint(1), newpoint(2));
            point.XData = newpoint(1);
            point.YData = newpoint(2);

            oldX = lineplot.XData;
            oldY = lineplot.YData;

            if isequal(point.UserData, lineplot.UserData.P1)
                % p1 was dragged

                lineplot.XData = [endp.Pos(1) oldX(2)];
                lineplot.YData = [endp.Pos(2) oldY(2)];
            else 
                % p2 was dragged

                lineplot.XData = [oldX(1) endp.Pos(1)];
                lineplot.YData = [oldY(1) endp.Pos(2)];
            end

            % display in tabgroup
            app.CreateTabGroup.SelectedTab = app.LineSegTab;
            app.LSX1EditField.Value = lineplot.XData(1);
            app.LSY1EditField.Value = lineplot.YData(1);
            app.LSX2EditField.Value = lineplot.XData(2);
            app.LSY2EditField.Value = lineplot.YData(2);

        end
        
        function redraw_ls_fromline(app, lineplot, p1, p2)
            % if linesegment is dragged by the line
            % update both endp positions
            % draggable objects can set_position
            
            % round to 0.1
            newX = round(lineplot.XData,1);
            newY = round(lineplot.YData,1);

            % snap to nearest points
            line = lineplot.UserData;            
            pointList = line.Frame.PointList;
            segList = line.Frame.SegmentList;

            for i = 1:length(segList)                
                if ~isequal(segList(i), line)
                    % check if 1st vertex matches with P1 or P2
                    if dist(app, segList(i).P1.Pos, [newX(1), newY(1)]) < app.SnapDist
                        newX(1) =  segList(i).P1.Pos(1);
                        newY(1) =  segList(i).P1.Pos(2);
                    elseif dist(app, segList(i).P2.Pos,[newX(1), newY(1)]) < app.SnapDist
                        newX(1) =  segList(i).P2.Pos(1);
                        newY(1) =  segList(i).P2.Pos(2);
                    end
                    % check if 2nd vertex matches with P1 or P2
                    if dist(app, segList(i).P1.Pos, [newX(2), newY(2)]) < app.SnapDist
                        newX(2) =  segList(i).P1.Pos(1);
                        newY(2) =  segList(i).P1.Pos(2);
                    elseif dist(app, segList(i).P2.Pos,[newX(2), newY(2)]) < app.SnapDist
                        newX(2) =  segList(i).P2.Pos(1);
                        newY(2) =  segList(i).P2.Pos(2);
                    end
                end
            end
            for i = 1:length(pointList)
                if dist(app, pointList(i).Pos, [newX(1), newY(1)]) < app.SnapDist
                    newX(1) = pointList(i).Pos(1);
                    newY(1) = pointList(i).Pos(2);
                elseif dist(app, pointList(i).Pos, [newX(2), newY(2)]) < app.SnapDist
                    newX(2) = pointList(i).Pos(1);
                    newY(2) = pointList(i).Pos(2);
                end
            end

            % set updated newX and newY
            newX = [newX(1), newX(2)];
            newY = [newY(1), newY(2)];

            % frontend
            lineplot.XData = newX;
            lineplot.YData = newY;
            p1.XData = newX(1);
            p1.YData = newY(1);
            p2.XData = newX(2);
            p2.YData = newY(2);    
            
            % backend
            line.P1.setPos(newX(1), newY(1));
            line.P2.setPos(newX(2), newY(2));

            % display in tabgroup
            app.CreateTabGroup.SelectedTab = app.LineSegTab;
            app.LSX1EditField.Value = line.P1.Pos(1);
            app.LSY1EditField.Value = line.P1.Pos(2);
            app.LSX2EditField.Value = line.P2.Pos(1);
            app.LSY2EditField.Value = line.P2.Pos(2);
%             app.LSX1EditField.Value = lineplot.XData(1);
%             app.LSY1EditField.Value = lineplot.YData(1);
%             app.LSX2EditField.Value = lineplot.XData(2);
%             app.LSY2EditField.Value = lineplot.YData(2);

        end
        
        
        function on_click(app, g)

            ax = app.UIAxes;

            if ~app.GroupSelect

                 % find all children, non-hgtransform, in figure
                allchildren = findobj(ax, '-depth', 2, ...
                    '-not', 'Type','hgtransform', '-not', 'Type', 'axes');
                
                % if selected is part of transformed group. store in
                % axes.UserData
                transformed = [];
                if isequal(g.Parent.Type, "hgtransform")
                    transformed = findobj(g.Parent,'-not','Type','hgtransform');
                    ax.UserData = g.Parent;
                else
                    % "selected" is a single plot if it's not connected to any
                    % transform groups, but if it is, the "selected" object is
                    % the whole group
                    ax.UserData = g;
                end
    
    
                % set selected as red, all other objects in uiaxes blue
                for i = 1:length(allchildren)
                    % if part of hgtransform group
                    if ismember(allchildren(i), transformed)
                        allchildren(i).Color = 'r';
                    % if just by itself (point)
                    elseif isequal(allchildren(i), g)
                        allchildren(i).Color = 'r';
                    else
                        allchildren(i).Color = 'b';
                    end
                end
                
                % display in the tab groups
                update_sidebar(app, g);
            
            else % group selection!
                % find all children, non-hgtransform, in figure
                allchildren = findobj(ax, '-depth', 2, ...
                    '-not', 'Type','hgtransform', '-not', 'Type', 'axes');
                
                % if selected is part of transformed group. store in
                % axes.UserData
                if isequal(g.Parent.Type, "hgtransform")
                    transformed = findobj(g.Parent,'-not','Type','hgtransform');
                    if isempty(ax.UserData)
                        ax.UserData = g.Parent;
                    else
                        ax.UserData(end+1) = g.Parent;
                    end
                else
                    warning("Non-segments can't be added to a group selection");
                    return
                end
    
                % set selected as red, others stay the same (DIFF)
                for i = 1:length(allchildren)
                    % if part of hgtransform group
                    if ismember(allchildren(i), transformed)
                        allchildren(i).Color = 'r';
                    end
                end
            end
        end
        
        function redraw_arc_fromp1(app, arcplot, p1, p2, newpoint)
            % p1: changes radius

            arc = arcplot.UserData;
            % C and theta don't change
            cx = arc.C.Pos(1);
            cy = arc.C.Pos(2);
            theta = arc.Theta;
            % p1 is based on newpoint
            px = round(newpoint(1), 1);
            py = round(newpoint(2), 1);

            % snap to nearest points
            pointList = arc.Frame.PointList;
            segList = arc.Frame.SegmentList;

            % only check for P1 matching
            for i = 1:length(segList)                
                if ~isequal(segList(i), arc)
                    % check if 1st vertex matches with P1 or P2
                    if dist(app, segList(i).P1.Pos, [px,py]) < app.SnapDist
                        px =  segList(i).P1.Pos(1);
                        py =  segList(i).P1.Pos(2);
                    elseif dist(app, segList(i).P2.Pos,[px,py]) < app.SnapDist
                        px =  segList(i).P2.Pos(1);
                        py =  segList(i).P2.Pos(2);
                    end
                end
            end
            for i = 1:length(pointList)
                if dist(app, pointList(i).Pos, [px, py]) < app.SnapDist
                    px = pointList(i).Pos(1);
                    py = pointList(i).Pos(2);
                end
            end

            % update arc backend
            arc.setP1(px, py);
            % update arcplot
            xy_r = draw_arc(app, cx, cy, px, py, theta);
            arcplot.XData = xy_r(1,:);
            arcplot.YData = xy_r(2,:);
            % update p1
            p1.XData = px;
            p1.YData = py;
            % update p2
            p2.XData = arc.P2.Pos(1);
            p2.YData = arc.P2.Pos(2);

            % display in tabgroup
            app.CreateTabGroup.SelectedTab = app.ArcTab;
            app.ArcPxEditField.Value = arcplot.XData(1);
            app.ArcPyEditField.Value = arcplot.YData(1);

            app.DisplayP2Label.Text = sprintf("[%.2f, %.2f]", arc.P2.Pos(1), arc.P2.Pos(2));
            app.DisplayRadiusLabel.Text = sprintf("%.2f", arc.getRadius);
        end

        function redraw_arc_fromp2(app, point, arcplot, newpoint)
            % p2: changes theta
            
            arc = arcplot.UserData;
            % C and p don't change
            cx = arc.C.Pos(1);
            cy = arc.C.Pos(2);
            px = arc.P1.Pos(1);
            py = arc.P1.Pos(2);

            % theta changes, recalc P2
            % round theta to nearest 1
            dir = round(mod(atan2d(newpoint(2) - cy,newpoint(1) - cx), 360)); 
            arc.Theta = dir;
            arc.calcP2;

            % update arcplot
            xy_r = draw_arc(app, cx, cy, px, py, arc.Theta);
            arcplot.XData = xy_r(1,:);
            arcplot.YData = xy_r(2,:);
            % update point
            point.XData = arc.P2.Pos(1);
            point.YData = arc.P2.Pos(2);

            % display in tabgroup
            app.CreateTabGroup.SelectedTab = app.ArcTab;
            app.ArcThetaEditField.Value = arc.Theta;
            app.DisplayP2Label.Text = sprintf("[%.2f, %.2f]", arc.P2.Pos(1), arc.P2.Pos(2));
        end

        function redraw_arc_fromline(app, arcplot, p1, p2, translated)
            % translation
            
            % get old p1 data
            arc = arcplot.UserData;
            oldpx = arc.P1.Pos(1);
            oldpy = arc.P1.Pos(2);
            
            % get new p1 data
            % round here to 0.1
            px = round(arcplot.XData(1),1);
            py = round(arcplot.YData(1),1);

            % find delta p1 make translation calc easier
            dx = px - oldpx;
            dy = py - oldpy;

            % only assume translation if dragged/translated
            if translated
                % change C in the backend
                arc.C.Pos = [arc.C.Pos(1) + dx,arc.C.Pos(2) + dy];
            end

            % change setPos P1, calcP2
            arc.setP1(px, py);

            % update P1 and P2
            p1.XData = arc.P1.Pos(1);
            p1.YData = arc.P1.Pos(2);
            p2.XData = arc.P2.Pos(1);
            p2.YData = arc.P2.Pos(2);

            % display in tabgroup
            app.CreateTabGroup.SelectedTab = app.ArcTab;
            app.ArcCxEditField.Value = arc.C.Pos(1);
            app.ArcCyEditField.Value = arc.C.Pos(2);
            app.ArcPxEditField.Value = arc.P1.Pos(1);
            app.ArcPyEditField.Value = arc.P1.Pos(2);
            app.DisplayP2Label.Text = sprintf("[%.2f, %.2f]", arc.P2.Pos(1), arc.P2.Pos(2));
            app.DisplayRadiusLabel.Text = sprintf("%.2f", arc.getRadius);
        end
        
        function update_sidebar(app, g)
            if isa(g.UserData, 'Point')
                app.CreateTabGroup.SelectedTab = app.PointTab;
                app.PointXEditField.Value = g.XData(1);
                app.PointYEditField.Value = g.YData(1);
            elseif isa(g.UserData, 'LineSegment')
                app.CreateTabGroup.SelectedTab = app.LineSegTab;
                app.LSX1EditField.Value = g.XData(1);
                app.LSY1EditField.Value = g.YData(1);
                app.LSX2EditField.Value = g.XData(2);
                app.LSY2EditField.Value = g.YData(2);
            elseif isa(g.UserData, 'Arc')
                app.CreateTabGroup.SelectedTab = app.ArcTab;
                app.ArcCxEditField.Value = g.UserData.C.Pos(1);
                app.ArcCyEditField.Value = g.UserData.C.Pos(2);
                app.ArcPxEditField.Value = g.UserData.P1.Pos(1);
                app.ArcPyEditField.Value = g.UserData.P1.Pos(2);
                app.ArcThetaEditField.Value = g.UserData.Theta;
                app.DisplayP2Label.Text = sprintf("[%.2f, %.2f]", ...
                    g.UserData.P2.Pos(1), g.UserData.P2.Pos(2));
                app.DisplayRadiusLabel.Text = sprintf("%.2f", ...
                    g.UserData.getRadius);
            elseif isa(g.UserData, 'Endpoint')
                if isa(g.UserData.Segment, 'LineSegment')
                    app.CreateTabGroup.SelectedTab = app.LineSegTab;
                    ls = g.UserData.Segment;
                    app.LSX1EditField.Value = ls.P1.Pos(1);
                    app.LSY1EditField.Value = ls.P1.Pos(2);
                    app.LSX2EditField.Value = ls.P2.Pos(1);
                    app.LSY2EditField.Value = ls.P2.Pos(2);
                elseif isa(g.UserData.Segment, 'Arc')
                    app.CreateTabGroup.SelectedTab = app.ArcTab;
                    arc = g.UserData.Segment;
                    app.ArcCxEditField.Value = arc.C.Pos(1);
                    app.ArcCyEditField.Value = arc.C.Pos(2);
                    app.ArcPxEditField.Value = arc.P1.Pos(1);
                    app.ArcPyEditField.Value = arc.P1.Pos(2);
                    app.ArcThetaEditField.Value = arc.Theta;
                    app.DisplayP2Label.Text = sprintf("[%.2f, %.2f]",arc.P2.Pos(1), arc.P2.Pos(2));
                    app.DisplayRadiusLabel.Text = sprintf("%.2f", arc.getRadius);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Frame = Frame;

            app.SnapDist = 0.3;
            
            ax = app.UIAxes();
            ax.Title.String = app.Frame.Title;

            % group select
            app.GroupSelect = false;
            app.DisplayGroupSelectLabel.Text = 'OFF';

            axis(ax, "equal");
            box(ax, 'on');
            grid(ax, 'on')
            set(ax, "DataAspectRatio", [1 1 1], "Xlim", [0 10], "YLim", [0 10]);
            
            xticks(ax, 0:10);
            yticks(ax, 0:10);

            set(ax,'XMinorTick','on','YMinorTick','on')
            hold(ax, "on")
        end

        % Button pushed function: CreatePoint
        function CreatePointPushed(app, event)
            
            x = app.PointXEditField.Value;
            y = app.PointYEditField.Value;
            p = Point(app.Frame, x, y);

            plotp = plot(app.UIAxes, x, y);
            plotp.UserData = p;

            % *** on_click
            draggable(plotp, @(g)update_point(app, plotp),...
                @(g)on_click(app,plotp));

            % for visible vertices
            set(plotp, "Marker", "o", "MarkerSize", 10, "Color", "b", ...
                "MarkerFaceColor", "b", 'LineWidth', 2);
        end

        % Button pushed function: CreateLS
        function CreateLSButtonPushed(app, event)
            
            ax = app.UIAxes;
            x1 = app.LSX1EditField.Value;
            y1 = app.LSY1EditField.Value;
            x2 = app.LSX2EditField.Value;
            y2 = app.LSY2EditField.Value;
            lineseg = LineSegment(app.Frame, x1, y1, x2, y2);

            hg = hgtransform('Parent',ax);

            % make line
            lineplot = line(ax, [x1 x2], [y1 y2], 'Parent', hg);
            % store linesegment backend in line plot
            lineplot.UserData = lineseg;
            
            set(lineplot, "Color", "b", "LineWidth", 2);
            
            % make vertices
            p1 = plot(ax, x1, y1, 'Parent', hg);
            p2 = plot(ax, x2, y2, 'Parent', hg);
            % store backend in each line plot
            p1.UserData = lineseg.P1;
            p2.UserData = lineseg.P2;
            % make vertices visible
            points = [p1 p2];
            set(points, "Marker", "o", "MarkerSize", 10, "Color", "b", ...
                "MarkerFaceColor", "b", 'LineWidth', 2);


            % with 0.1 rounding
            draggable(p1, @(g)redraw_ls_frompoint(app, p1, lineplot, ...
                round(ax.CurrentPoint(1,1:2), 1)), @(g)on_click(app,p1));
            draggable(p2, @(g)redraw_ls_frompoint(app, p2, lineplot,...
                round(ax.CurrentPoint(1,1:2), 1)), @(g)on_click(app,p2));
            draggable(lineplot, @(g)redraw_ls_fromline(app, lineplot, p1, p2),...
                @(g)on_click(app,lineplot));

        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            try 
                app.Frame.validate;
                app.ValidateStatusLabel.Text = 'Success!';
            catch 
                warning("Path validation was unsuccessful")
                app.ValidateStatusLabel.Text = 'Path validation was unsuccessful';
            end
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            try
                app.Frame.export;
                app.ExportStatusLabel.Text = 'Success!';
            catch
                warning("Export failed. Please first complete validated path.");
                app.ExportStatusLabel.Text = 'Export was unsuccessful';
            end
        end

        % Button pushed function: SetLS
        function SetLSPushed(app, event)
            if length(app.UIAxes.UserData) > 1
                warning("Can't use set functions on a group selection")
                return
            end
            
            x1 = app.LSX1EditField.Value;
            y1 = app.LSY1EditField.Value;
            x2 = app.LSX2EditField.Value;
            y2 = app.LSY2EditField.Value;

            selected = app.UIAxes.UserData;
                
            if ~isempty(selected)
                if isequal(selected.Type, "hgtransform")
                    % find the line objects in hgtransform group
                    ls = findobj(selected, 'Type', 'line', 'Marker', 'none');
                    if ~isempty(ls)
                        % check if line objects is a linesegment
                        if isa(ls(1).UserData, 'LineSegment')
                            ls(1).UserData.setPos(x1, y1, x2, y2);
    
                            ls(1).XData = [x1 x2];
                            ls(1).YData = [y1 y2];
    
                            vertices = findobj(selected, 'Type', 'line', 'Marker', 'o');
                            if isequal(vertices(1).UserData, ls(1).UserData.P1)
                                % first in vertices array is P1
                                redraw_ls_fromline(app, ls(1), vertices(1), vertices(2));
                            else
                                % first in vertices array is P2
                                redraw_ls_fromline(app, ls(1), vertices(2), vertices(1));
                            end
                            return
                        end
                    end
                end
            end

            warning("Please select a line segment to set");
        end

        % Button pushed function: SetPoint
        function SetPointButtonPushed(app, event)
            if length(app.UIAxes.UserData) > 1
                warning("Can't use set functions on a group selection")
                return
            end
            
            x = app.PointXEditField.Value;
            y = app.PointYEditField.Value;

            selected = app.UIAxes.UserData;

            if ~isempty(selected)
                if isa(selected.UserData, "Point")
                    selected.XData = x;
                    selected.YData = y;
        
                    selected.UserData.Pos = [x y];
                    return
                end
            end

             warning("Please select a point to set");
        end

        % Button pushed function: CreateArc
        function CreateArcButtonPushed(app, event)
            ax = app.UIAxes;
            
            cx = app.ArcCxEditField.Value;
            cy = app.ArcCyEditField.Value;
            px = app.ArcPxEditField.Value;
            py = app.ArcPyEditField.Value;
            theta = app.ArcThetaEditField.Value;

            arc = Arc(app.Frame, cx, cy, px, py, theta); 
            
            xy_r = draw_arc(app, cx, cy, px, py, theta);

            hg = hgtransform('Parent',ax);
            
            % plot arc
            arcplot = plot(ax, xy_r(1,:), xy_r(2,:), 'Parent', hg);
            arcplot.UserData = arc;

            set(arcplot, "Color", "b", "LineWidth", 2);
            
            % make vertices
            p1 = plot(ax, arc.P1.Pos(1), arc.P1.Pos(2), 'Parent', hg);
            p2 = plot(ax, arc.P2.Pos(1), arc.P2.Pos(2), 'Parent', hg);
            % store backend in each line plot
            p1.UserData = arc.P1;
            p2.UserData = arc.P2;
            % make vertices visible
            points = [p1 p2];
            set(points, "Marker", "o", "MarkerSize", 10, "Color", "b", ...
                "MarkerFaceColor", "b", 'LineWidth', 2);

            % round fromp1 to closest 0.1
            draggable(p1, @(g)redraw_arc_fromp1(app, arcplot, p1, p2, ...
                round(ax.CurrentPoint(1,1:2), 1)), @(g)on_click(app,p1));
            % round theta for fromp2 and p1/C for fromline in onmovefcn
            draggable(p2, @(g)redraw_arc_fromp2(app, p2, arcplot,...
                ax.CurrentPoint(1,1:2)), @(g)on_click(app,p2));
            draggable(arcplot, @(g)redraw_arc_fromline(app, arcplot, p1, p2, true),...
                @(g)on_click(app,arcplot));

            % set labels for radius and P2
            app.DisplayP2Label.Text = sprintf("[%.2f, %.2f]", arc.P2.Pos(1), arc.P2.Pos(2));
            app.DisplayRadiusLabel.Text = sprintf("%.2f", arc.getRadius);
        end

        % Button pushed function: SetArc
        function SetArcButtonPushed(app, event)
            if length(app.UIAxes.UserData) > 1
                warning("Can't use set functions on a group selection")
                return
            end
            
            cx = app.ArcCxEditField.Value;
            cy = app.ArcCyEditField.Value;
            px = app.ArcPxEditField.Value;
            py = app.ArcPyEditField.Value;
            theta = app.ArcThetaEditField.Value;

            selected = app.UIAxes.UserData;
                
            if ~isempty(selected)
                if isequal(selected.Type, "hgtransform")
                   % different concept then setls: completely redraws arc
                   % backend
                    
                    % find the line objects in hgtransform group
                    arcplot = findobj(selected, 'Type', 'line', 'Marker', 'none');
                    if ~isempty(arcplot)
                        % check if line objects is an arc
                        if isa(arcplot(1).UserData, 'Arc')
                            % store old arc handle object in another variable
                            old_arc = arcplot(1).UserData;
                            % make new Arc object to replace
                            arcplot(1).UserData = Arc(app.Frame,cx,cy,px,py,theta);
                            arc = arcplot(1).UserData;
    
                            % draw new arcplot
                            coords = draw_arc(app, cx,cy,px,py,theta);
                            arcplot(1).XData = coords(1,:);
                            arcplot(1).YData = coords(2,:);
                            
                            % draw p1 and p2
                            vertices = findobj(selected, 'Type', 'line', 'Marker', 'o');
                            oldpoint1 = vertices(1).UserData;
                            oldpoint2 = vertices(2).UserData;
                            if isequal(vertices(1).UserData, old_arc.P1)
                                % first in vertices array was the original P1
    
                                % store P1 and P2 in the UserData
                                vertices(1).UserData = arc.P1;
                                vertices(2).UserData = arc.P2;
    
                                redraw_arc_fromline(app, arcplot(1), vertices(1), vertices(2),true);
                            else
                                % first in vertices array was the original P2
                                
                                % store P1 and P2 in the UserData
                                vertices(2).UserData = arc.P1;
                                vertices(1).UserData = arc.P2;
                                
                                redraw_arc_fromline(app, arcplot(1), vertices(2), vertices(1),true);
                            end
                            % delete handle objects
                            delete(old_arc);
                            delete(oldpoint1);
                            delete(oldpoint2);

                            % display P2 and radius
                            app.DisplayP2Label.Text = sprintf("[%.2f, %.2f]", ...
                                arc.P2.Pos(1), arc.P2.Pos(2));
                            app.DisplayRadiusLabel.Text = sprintf("%.2f", ...
                                arc.getRadius);

                            return
                        end
                    end
                end
            end

            warning("Please select a line segment to set");            
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            selected = app.UIAxes.UserData;
                
            if ~isempty(selected)
                for i = 1:length(selected)
                    if isequal(selected(i).Type, "hgtransform")
                        % find the line objects in hgtransform group
                        selectedplots = findobj(selected(i), 'Type', 'line');
                        if ~isempty(selectedplots)
                            for j = 1:length(selectedplots)
                                % delete backend, only need to delete from
                                % segment
                                subseg = selectedplots(j).UserData;
                                if ~isa(subseg, "Endpoint")
                                    subseg.Frame.delete(subseg);
                                end
                                % delete plot
                                delete(selectedplots(j));
                            end
                        end
                    elseif isequal(selected(i).Type, "line")
                        point = selected.UserData;
                        point.Frame.delete(point);
                        delete(selected(i));
                    end
                end
                return
            end

            warning("Please select an object to delete");
        end

        % Button pushed function: TranslateButton
        function TranslateButtonPushed(app, event)
            dx = app.TranslateXEditField.Value;
            dy = app.TranslateYEditField.Value;
            
            selected = app.UIAxes.UserData;

            if ~isempty(selected)
                for i = 1:length(selected)
                    if isequal(selected(i).Type, "hgtransform")
                        segplot = findobj(selected(i), 'Type', 'line', 'Marker', 'none');
                        seg = segplot(1).UserData;

                        vertices = findobj(selected(i), 'Type', 'line', 'Marker', 'o');
                        if isequal(vertices(1), seg.P1)
                            p1 = vertices(1);
                            p2 = vertices(2);
                        else
                            p1 = vertices(2);
                            p2 = vertices(1);
                        end
                        
                        if isa(seg, "LineSegment")
                            seg.translate(dx,dy);
                            
                            segplot(1).XData = [seg.P1.Pos(1), seg.P2.Pos(1)];
                            segplot(1).YData = [seg.P1.Pos(2), seg.P2.Pos(2)];
    
                            redraw_ls_fromline(app, segplot(1),p1,p2);
                        
                        elseif isa(seg, "Arc")
                            cx = seg.C.Pos(1) + dx;
                            cy = seg.C.Pos(2) + dy;
                            px = seg.P1.Pos(1) + dx;
                            py = seg.P1.Pos(2) + dy;
                            
                            xy_r = draw_arc(app, cx,cy,px,py, seg.Theta);
                            segplot(1).XData = xy_r(1,:);
                            segplot(1).YData = xy_r(2,:);
                
                            redraw_arc_fromline(app, segplot(1),p1,p2, true);
                        end 
                    end
                end
                if length(selected)==1
                    update_sidebar(app, segplot(1));
                end
                return
            end
            warning("Please select a segment to translate")
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            x = app.ScaleXEditField.Value;
            y = app.ScaleYEditField.Value;
            factor = app.ScaleFactorEditField.Value;

            selected = app.UIAxes.UserData;

            if ~isempty(selected)
                for i = 1:length(selected)
                    if isequal(selected(i).Type, "hgtransform")
                        segplot = findobj(selected(i), 'Type', 'line', 'Marker', 'none');
                        seg = segplot(1).UserData;

                        vertices = findobj(selected(i), 'Type', 'line', 'Marker', 'o');
                        if isequal(vertices(1), seg.P1)
                            p1 = vertices(1);
                            p2 = vertices(2);
                        else
                            p1 = vertices(2);
                            p2 = vertices(1);
                        end
                        
                        if isa(seg, "LineSegment")
                            seg.scale([x y], factor);
                            
                            segplot(1).XData = [seg.P1.Pos(1), seg.P2.Pos(1)];
                            segplot(1).YData = [seg.P1.Pos(2), seg.P2.Pos(2)];
    
                            redraw_ls_fromline(app, segplot(1),p1,p2);
                        
                        elseif isa(seg, "Arc")
                            point = [x y];

                            dist1 = dist(app, point, seg.P1.Pos);
                            dir1 = dir(app, point, seg.P1.Pos);
                            newDist1 = factor * dist1;
                            px = point(1) + newDist1 * cosd(dir1);
                            py = point(2) + newDist1 * sind(dir1);
                
                            distC = dist(app, point, seg.C.Pos);
                            dirC = dir(app, point, seg.C.Pos);
                            newDistC = factor * distC;
                            cx = point(1) + newDistC * cosd(dirC);
                            cy = point(2) + newDistC * sind(dirC);
                            
                            xy_r = draw_arc(app,cx,cy,px,py, seg.Theta);
                            segplot(1).XData = xy_r(1,:);
                            segplot(1).YData = xy_r(2,:);
                
                            redraw_arc_fromline(app, segplot(1),p1,p2, false);
                        end
                    end
                end
                if length(selected)==1
                    update_sidebar(app, segplot(1));
                end
                return
            end
            warning("Please select a segment to scale")
        end

        % Button pushed function: PivotButton
        function PivotButtonPushed(app, event)
            x = app.PivotXEditField.Value;
            y = app.PivotYEditField.Value;
            deg = app.PivotDegreesEditField.Value;

            selected = app.UIAxes.UserData;

            if ~isempty(selected)
                for i = 1:length(selected)
                    if isequal(selected(i).Type, "hgtransform")
                        segplot = findobj(selected(i), 'Type', 'line', 'Marker', 'none');
                        seg = segplot(1).UserData;

                        vertices = findobj(selected(i), 'Type', 'line', 'Marker', 'o');
                        if isequal(vertices(1), seg.P1)
                            p1 = vertices(1);
                            p2 = vertices(2);
                        else
                            p1 = vertices(2);
                            p2 = vertices(1);
                        end
                        
                        if isa(seg, "LineSegment")
                            seg.pivot([x y], deg);
                            
                            segplot(1).XData = [seg.P1.Pos(1), seg.P2.Pos(1)];
                            segplot(1).YData = [seg.P1.Pos(2), seg.P2.Pos(2)];
    
                            redraw_ls_fromline(app, segplot(1),p1,p2);
                        
                        elseif isa(seg, "Arc")
                            point = [x y];

                            dist1 = dist(app, point, seg.P1.Pos);
                            dir1 = dir(app, point, seg.P1.Pos);
                            newDir1 = dir1 + deg;
                            px = point(1) + dist1 * cosd(newDir1);
                            py = point(2) + dist1 * sind(newDir1);
                
                            distC = dist(app, point, seg.C.Pos);
                            dirC = dir(app, point, seg.C.Pos);
                            newDirC = dirC + deg;
                            cx = point(1) + distC * cosd(newDirC);
                            cy = point(2) + distC * sind(newDirC);

                            seg.C.Pos = [cx cy];
                            
                            xy_r = draw_arc(app,cx,cy,px,py, seg.Theta);
                            segplot(1).XData = xy_r(1,:);
                            segplot(1).YData = xy_r(2,:);
                
                            redraw_arc_fromline(app, segplot(1),p1,p2, false);
                        end
                    end
                end
                if length(selected)==1
                    update_sidebar(app, segplot(1));
                end
                return
            end
            warning("Please select a segment to pivot")          
        end

        % Button pushed function: LengthenButton
        function LengthenButtonPushed(app, event)
            if length(app.UIAxes.UserData) > 1
                warning("Can't use lengthen on a group selection")
                return
            end

            selected = app.UIAxes.UserData;
            newLength = app.LengthNewLEditField.Value;

            if ~isempty(selected)
                if isequal(selected.Type, "hgtransform")
                    segplot = findobj(selected, 'Type', 'line', 'Marker', 'none');
                    seg = segplot(1).UserData;
                    if isa(seg, "LineSegment")
                        % backend point
                        if app.P1Button.Value == true
                            endp = seg.P1;
                        else
                            endp = seg.P2;
                        end

                        % front end point
                        vertices = findobj(selected, 'Type', 'line', ...
                                'Marker', 'o');
                        if isequal(vertices(1).UserData, endp)
                            point = vertices(1);
                        else
                            point = vertices(2);
                        end
                        
                        seg.lengthen(endp, newLength);
                        
                        redraw_ls_frompoint(app, point, segplot(1), endp.Pos);
    
                        update_sidebar(app, segplot(1));
                        return
                    end
                end
            end
            warning("Please select a line segment to lengthen")  
        end

        % Button pushed function: NameButton
        function NameButtonPushed(app, event)
            name = app.FileNameEditField.Value;
            app.Frame.Title = name;
            app.UIAxes.Title.String = app.Frame.Title;
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            ax = app.UIAxes;
            switch key
                case 'shift'
                    ax.UserData = [];
                     
                    % find all children, non-hgtransform, in figure
                    allchildren = findobj(ax, '-depth', 2, ...
                        '-not', 'Type','hgtransform', '-not', 'Type', 'axes');
                    % set all blue
                    for i = 1:length(allchildren)
                        allchildren(i).Color = 'b';
                    end
                    
                    app.GroupSelect = true;
                    app.DisplayGroupSelectLabel.Text = 'ON';
            end
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'shift'
                    app.GroupSelect = false;
                    app.DisplayGroupSelectLabel.Text = 'OFF';
            end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            if length(app.UIAxes.UserData) > 1
                warning("Can't set resolution on a group selection")
                return
            end

            selected = app.UIAxes.UserData;
            res = app.SetexportresolutionEditField.Value;

            if ~isempty(selected)
                if isequal(selected.Type, "hgtransform")
                    segplot = findobj(selected, 'Type', 'line', 'Marker', 'none');
                    seg = segplot(1).UserData;
                    seg.Resolution = res;
                    app.SetexportresolutionEditField.Value = seg.Resolution;
                    return
                end
            end
            warning("Select a segment to set its resolution")
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CreatePanel = uipanel(app.UIFigure);
            app.CreatePanel.Title = 'Create';
            app.CreateTabGroup = uitabgroup(app.CreatePanel);
            app.PointTab = uitab(app.CreateTabGroup);
            app.PointTab.Title = 'Point';
            app.CreatePoint = uibutton(app.PointTab, 'push');
            app.CreatePoint.ButtonPushedFcn = createCallbackFcn(app, @CreatePointPushed, true);
            app.CreatePoint.Text = 'Create';
            app.XEditFieldLabel = uilabel(app.PointTab);
            app.XEditFieldLabel.Text = 'X';
            app.PointXEditField = uieditfield(app.PointTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.PointTab);
            app.YEditFieldLabel.Text = 'Y';
            app.PointYEditField = uieditfield(app.PointTab, 'numeric');
            app.SetPoint = uibutton(app.PointTab, 'push');
            app.SetPoint.ButtonPushedFcn = createCallbackFcn(app, @SetPointButtonPushed, true);
            app.SetPoint.Text = 'Set';
            app.LineSegTab = uitab(app.CreateTabGroup);
            app.LineSegTab.Title = 'LineSeg';
            app.CreateLS = uibutton(app.LineSegTab, 'push');
            app.CreateLS.ButtonPushedFcn = createCallbackFcn(app, @CreateLSButtonPushed, true);
            app.CreateLS.Text = 'Create';
            app.X1EditFieldLabel = uilabel(app.LineSegTab);
            app.X1EditFieldLabel.Text = 'X1';
            app.LSX1EditField = uieditfield(app.LineSegTab, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.LineSegTab);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.LSY1EditField = uieditfield(app.LineSegTab, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.LineSegTab);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.LSY2EditField = uieditfield(app.LineSegTab, 'numeric');
            app.X2EditFieldLabel = uilabel(app.LineSegTab);
            app.X2EditFieldLabel.Text = 'X2';
            app.LSX2EditField = uieditfield(app.LineSegTab, 'numeric');
            app.LSX2EditField.Value = 1;
            app.SetLS = uibutton(app.LineSegTab, 'push');
            app.SetLS.ButtonPushedFcn = createCallbackFcn(app, @SetLSPushed, true);
            app.SetLS.Text = 'Set';
            app.ArcTab = uitab(app.CreateTabGroup);
            app.ArcTab.Title = 'Arc';
            app.CreateArc = uibutton(app.ArcTab, 'push');
            app.CreateArc.ButtonPushedFcn = createCallbackFcn(app, @CreateArcButtonPushed, true);
            app.CreateArc.Text = 'Create';
            app.SetArc = uibutton(app.ArcTab, 'push');
            app.SetArc.ButtonPushedFcn = createCallbackFcn(app, @SetArcButtonPushed, true);
            app.SetArc.Text = 'Set';
            app.CxLabel = uilabel(app.ArcTab);
            app.CxLabel.Text = 'Cx';
            app.ArcCxEditField = uieditfield(app.ArcTab, 'numeric');
            app.CyLabel = uilabel(app.ArcTab);
            app.CyLabel.Text = 'Cy';
            app.ArcCyEditField = uieditfield(app.ArcTab, 'numeric');
            app.X1Label = uilabel(app.ArcTab);
            app.X1Label.Text = 'Px';
            app.ArcPxEditField = uieditfield(app.ArcTab, 'numeric');
            app.ArcPxEditField.Value = 1;
            app.Y1Label = uilabel(app.ArcTab);
            app.Y1Label.Text = 'Py';
            app.ArcPyEditField = uieditfield(app.ArcTab, 'numeric');
            app.AngleLabel = uilabel(app.ArcTab);
            app.AngleLabel.Text = 'Angle';
            app.ArcThetaEditField = uieditfield(app.ArcTab, 'numeric');
            app.ArcThetaEditField.Value = 90;
            app.P2Label = uilabel(app.ArcTab);
            app.P2Label.Text = 'P2';
            app.DisplayP2Label = uilabel(app.ArcTab);
            app.DisplayP2Label.Text = '';
            app.RadiusLabel = uilabel(app.ArcTab);
            app.RadiusLabel.Text = 'Radius';
            app.DisplayRadiusLabel = uilabel(app.ArcTab);
            app.DisplayRadiusLabel.Text = '';
            app.TransformPanel = uipanel(app.UIFigure);
            app.TransformPanel.Title = 'Transform';
            app.TransformTabGroup = uitabgroup(app.TransformPanel);
            app.TranslateTab = uitab(app.TransformTabGroup);
            app.TranslateTab.Title = 'Translate';
            app.dxLabel = uilabel(app.TranslateTab);
            app.dxLabel.Text = 'dx';
            app.TranslateXEditField = uieditfield(app.TranslateTab, 'numeric');
            app.dyLabel = uilabel(app.TranslateTab);
            app.dyLabel.Text = 'dy';
            app.TranslateYEditField = uieditfield(app.TranslateTab, 'numeric');
            app.TranslateButton = uibutton(app.TranslateTab, 'push');
            app.TranslateButton.ButtonPushedFcn = createCallbackFcn(app, @TranslateButtonPushed, true);
            app.TranslateButton.Text = 'Translate';
            app.ScaleTab = uitab(app.TransformTabGroup);
            app.ScaleTab.Title = 'Scale';
            app.ScaleButton = uibutton(app.ScaleTab, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.dxLabel_2 = uilabel(app.ScaleTab);
            app.dxLabel_2.Text = 'X';
            app.ScaleXEditField = uieditfield(app.ScaleTab, 'numeric');
            app.dyLabel_2 = uilabel(app.ScaleTab);
            app.dyLabel_2.Text = 'Y';
            app.ScaleYEditField = uieditfield(app.ScaleTab, 'numeric');
            app.AnchorLabel = uilabel(app.ScaleTab);
            app.AnchorLabel.Text = 'Anchor';
            app.dyLabel_3 = uilabel(app.ScaleTab);
            app.dyLabel_3.Text = 'Scale';
            app.ScaleFactorEditField = uieditfield(app.ScaleTab, 'numeric');
            app.PivotTab = uitab(app.TransformTabGroup);
            app.PivotTab.Title = 'Pivot';
            app.PivotButton = uibutton(app.PivotTab, 'push');
            app.PivotButton.ButtonPushedFcn = createCallbackFcn(app, @PivotButtonPushed, true);
            app.PivotButton.Text = 'Pivot';
            app.AnchorLabel_2 = uilabel(app.PivotTab);
            app.AnchorLabel_2.Text = 'Anchor';
            app.dxLabel_3 = uilabel(app.PivotTab);
            app.dxLabel_3.Text = 'X';
            app.PivotXEditField = uieditfield(app.PivotTab, 'numeric');
            app.dyLabel_4 = uilabel(app.PivotTab);
            app.dyLabel_4.Text = 'Y';
            app.PivotYEditField = uieditfield(app.PivotTab, 'numeric');
            app.dyLabel_5 = uilabel(app.PivotTab);
            app.dyLabel_5.Text = 'Degrees';
            app.PivotDegreesEditField = uieditfield(app.PivotTab, 'numeric');
            app.LengthenTab = uitab(app.TransformTabGroup);
            app.LengthenTab.Title = 'Lengthen';
            app.LengthenButton = uibutton(app.LengthenTab, 'push');
            app.LengthenButton.ButtonPushedFcn = createCallbackFcn(app, @LengthenButtonPushed, true);
            app.LengthenButton.Text = 'Lengthen';
            app.dyLabel_7 = uilabel(app.LengthenTab);
            app.dyLabel_7.Text = 'New Length';
            app.LengthNewLEditField = uieditfield(app.LengthenTab, 'numeric');
            app.LineSegmentonlyLabel = uilabel(app.LengthenTab);
            app.LineSegmentonlyLabel.Text = 'LineSegment only';
            app.ButtonGroup = uibuttongroup(app.LengthenTab);
            app.P1Button = uiradiobutton(app.ButtonGroup);
            app.P1Button.Text = 'P1';
            app.P1Button.Value = true;
            app.P2Button = uiradiobutton(app.ButtonGroup);
            app.P2Button.Text = 'P2';
            app.EndpointtoextendLabel = uilabel(app.ButtonGroup);
            app.EndpointtoextendLabel.Text = {'Endpoint '; 'to extend'};
            app.Panel = uipanel(app.UIFigure);
            app.ValidateButton = uibutton(app.Panel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.ValidateStatusLabel = uilabel(app.Panel);
            app.ValidateStatusLabel.Text = 'Status';
            app.ExportButton = uibutton(app.Panel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ExportStatusLabel = uilabel(app.Panel);
            app.ExportStatusLabel.Text = 'Status';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.NameButton = uibutton(app.UIFigure, 'push');
            app.NameButton.ButtonPushedFcn = createCallbackFcn(app, @NameButtonPushed, true);
            app.NameButton.Text = 'Name';
            app.GroupselectionLabel = uilabel(app.UIFigure);
            app.GroupselectionLabel.Text = 'Group selection:';
            app.DisplayGroupSelectLabel = uilabel(app.UIFigure);
            app.DisplayGroupSelectLabel.Text = '';
            app.SetexportresolutionEditFieldLabel = uilabel(app.UIFigure);
            app.SetexportresolutionEditFieldLabel.Text = {'Set export'; 'resolution'};
            app.SetexportresolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetexportresolutionEditField.Value = 20;
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = path_create_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end